classdef FinanceApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        TabGroup              matlab.ui.container.TabGroup
        PreTaxVoluntaryTab    matlab.ui.container.Tab
        PreTaxVoluntaryGrid   matlab.ui.container.GridLayout
        PreTaxCompulsoryTab   matlab.ui.container.Tab
        PreTaxCompulsoryGrid  matlab.ui.container.GridLayout
        PostTaxTab            matlab.ui.container.Tab
        PostTaxGrid           matlab.ui.container.GridLayout
        SettingsTab           matlab.ui.container.Tab
        SettingsGrid          matlab.ui.container.GridLayout
        CopyrightLabel        matlab.ui.control.Label
        GitHubLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Finance model computing net income from gross income.
        Model (1, 1)
        % Array of views used to visualize deductions.
        Views (1, :) component.Component = component.Null
        % Array of controllers used to edit model data.
        Controllers (1, :) component.Component = component.Null
    end % properties (Access = private)
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Import all MVC elements.
            import component.view.*
            import component.control.*
            
            % Create finance model.
            app.Model = Finance();
            app.UIFigure.Name = "Income Finance App";
            
            % Create income viewer.
            app.Views(1) = IncomeViewController(app.Model, ...
                "Parent", app.GridLayout);
            app.Views(1).Layout.Row = 1;
            app.Views(1).Layout.Column = [1, 2];
            
            % Crete deduction viewers.
            app.Views(2) = DeductionsView(app.Model, ...
                "Parent", app.PreTaxVoluntaryGrid, ...
                "TrackedProperty", "PreTax", ...
                "ColumnEditable", false, ...
                "ColumnSortable", [false, true, false, false]);
            
            app.Views(3) = DeductionsView(app.Model, ...
                "Parent", app.PreTaxCompulsoryGrid, ...
                "TrackedProperty", "TaxNITable", ...
                "ColumnEditable", false, ...
                "ColumnSortable", [false, true, false, false]);
            
            app.Views(4) = DeductionsView(app.Model, ...
                "Parent", app.PostTaxGrid, ...
                "TrackedProperty", "PostTax", ...
                "ColumnEditable", false, ...
                "ColumnSortable", [false, true, false, false]);
            
            % Create recurrence controllers.
            app.Controllers(1) = RecurrenceController(app.Model, ...
                "Parent", app.GridLayout, ...
                "Width", app.UIFigure.Position(3) - 40); % subtract padding
            app.Controllers(1).Layout.Row = 2;
            app.Controllers(1).Layout.Column = [1, 2];
            
            % Create deduction controllers.
            app.Controllers(2) = DeductionsController(app.Model, ...
                "Parent", app.UIFigure, ...
                "TrackedProperty", "PreTax");
            app.Views(2).ContextMenu = app.Controllers(2).UIContextMenu;
            
            app.Controllers(3) = DeductionsController(app.Model, ...
                "Parent", app.UIFigure, ...
                "TrackedProperty", "PostTax");
            app.Views(4).ContextMenu = app.Controllers(3).UIContextMenu;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PreTaxVoluntaryTab = uitab(app.TabGroup);
            app.PreTaxVoluntaryTab.Title = 'Pre-Tax Voluntary';
            app.PreTaxVoluntaryGrid = uigridlayout(app.PreTaxVoluntaryTab);
            app.PreTaxCompulsoryTab = uitab(app.TabGroup);
            app.PreTaxCompulsoryTab.Title = 'Pre-Tax Compulsory';
            app.PreTaxCompulsoryGrid = uigridlayout(app.PreTaxCompulsoryTab);
            app.PostTaxTab = uitab(app.TabGroup);
            app.PostTaxTab.Title = 'Post-Tax';
            app.PostTaxGrid = uigridlayout(app.PostTaxTab);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SettingsGrid = uigridlayout(app.SettingsTab);
            app.CopyrightLabel = uilabel(app.GridLayout);
            app.CopyrightLabel.Text = 'Copyright © Michele Facchinelli 2020';
            app.GitHubLabel = uilabel(app.GridLayout);
            app.GitHubLabel.Text = 'mfacchinelli.github.io';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinanceApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end